classdef simple_extract_data_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExtractDataOptionsGUIUIFigure  matlab.ui.Figure
        MarkPointsPanel                matlab.ui.container.Panel
        AnalysisDropDown               matlab.ui.control.DropDown
        AnalysisDropDownLabel          matlab.ui.control.Label
        ConcatenateBlocksPanel         matlab.ui.container.Panel
        ConcatenatebyDropDown          matlab.ui.control.DropDown
        ConcatenatebyDropDownLabel     matlab.ui.control.Label
        RemoveOldBlocksCheckBox        matlab.ui.control.CheckBox
        ConcatenateBlocksCheckBox      matlab.ui.control.CheckBox
        PeakDetectionPanel             matlab.ui.container.Panel
        PeakDurationEditField          matlab.ui.control.NumericEditField
        PeakDurationEditFieldLabel     matlab.ui.control.Label
        AUCLevelEditField              matlab.ui.control.NumericEditField
        AUCLevelEditFieldLabel         matlab.ui.control.Label
        ZLevelEditField                matlab.ui.control.NumericEditField
        ZLevelEditFieldLabel           matlab.ui.control.Label
        ReliabilityPanel               matlab.ui.container.Panel
        AdjustneighborpvalueCheckBox   matlab.ui.control.CheckBox
        AdjustpvalueCheckBox           matlab.ui.control.CheckBox
        ReliabilityTypeDropDown        matlab.ui.control.DropDown
        ReliabilityTypeDropDownLabel   matlab.ui.control.Label
        NeighborpvalueEditField        matlab.ui.control.NumericEditField
        NeighborpvalueEditFieldLabel   matlab.ui.control.Label
        ReliabilitypvalueEditField     matlab.ui.control.NumericEditField
        ReliabilitypvalueLabel         matlab.ui.control.Label
        ShuffleTypeDropDown            matlab.ui.control.DropDown
        ShuffleTypeDropDownLabel       matlab.ui.control.Label
        ReliabilityShufflesEditField   matlab.ui.control.NumericEditField
        ReliabilityShufflesEditFieldLabel  matlab.ui.control.Label
        UseNeighborsCheckBox           matlab.ui.control.CheckBox
        UseReliabilityCheckBox         matlab.ui.control.CheckBox
        RunButton                      matlab.ui.control.Button
        StatusLabel                    matlab.ui.control.Label
        SavingandPlottingPanel         matlab.ui.container.Panel
        PreviewBlockResponsesCheckBox  matlab.ui.control.CheckBox
        SaveRespCellFigsOnlyCheckBox   matlab.ui.control.CheckBox
        IncludeMatchesCheckBox         matlab.ui.control.CheckBox
        SaveFiguresCheckBox            matlab.ui.control.CheckBox
        PlotFiguresCheckBox            matlab.ui.control.CheckBox
        SaveExtractedDataCheckBox      matlab.ui.control.CheckBox
        PlotExtractedDataCheckBox      matlab.ui.control.CheckBox
        GlobalPanel                    matlab.ui.container.Panel
        RemoveUnbalancedStimCheckBox   matlab.ui.control.CheckBox
        CheckSingleTrialRespCheckBox   matlab.ui.control.CheckBox
        SaveFullTraceCheckBox          matlab.ui.control.CheckBox
        LocoFilterListBox              matlab.ui.control.ListBox
        LocoFilterListBoxLabel         matlab.ui.control.Label
        SmoothingWindowEditField       matlab.ui.control.NumericEditField
        SmoothingWindowEditFieldLabel  matlab.ui.control.Label
        StimProtocolDropDown           matlab.ui.control.DropDown
        StimProtocolLabel              matlab.ui.control.Label
        PathsPanel                     matlab.ui.container.Panel
        MatchFilePathClearButton       matlab.ui.control.Button
        SavePathClearButton            matlab.ui.control.Button
        BlocksPathClearButton          matlab.ui.control.Button
        InfoFilePathClearButton        matlab.ui.control.Button
        MatchFilePathSelectButton      matlab.ui.control.Button
        MatchFileEditField             matlab.ui.control.EditField
        MatchFileEditFieldLabel        matlab.ui.control.Label
        SavePathEditField              matlab.ui.control.EditField
        SavePathEditFieldLabel         matlab.ui.control.Label
        SavePathSelectButton           matlab.ui.control.Button
        BlocksPathEditField            matlab.ui.control.EditField
        BlocksPathEditFieldLabel       matlab.ui.control.Label
        BlocksPathSelectButton         matlab.ui.control.Button
        InfoFileEditField              matlab.ui.control.EditField
        InfoFileEditFieldLabel         matlab.ui.control.Label
        InfoFilePathSelectButton       matlab.ui.control.Button
        IOPanel                        matlab.ui.container.Panel
        ValidateopsButton              matlab.ui.control.Button
        LoadDemoButton                 matlab.ui.control.Button
        SaveAsButton                   matlab.ui.control.Button
        LoadButton                     matlab.ui.control.Button
    end

    
    properties (Access = private)
%         Ops % controls all options passed to extract data
        OpsFolder = parent(Path(mfilename("fullpath"))); 
        OPS_CURRENT_PATH = Path(); 
        OPS_META_PATH = Path(); 
        OpsMeta
    end
    
    properties (Access = public)
        Ops % Description
    end
    
    methods (Access = private)
        function [] = validate_ops(app)
            error("validate_ops() hasn't been implemented yet"); 
%             OpsNew = [];
%             for ivar = 1:height(app.OpsMeta)
%                 OpsNew.(app.OpsMeta.name{ivar}) = app.(app.OpsMeta.GUIname{ivar}).Value;
%             end    
% 
%             ops_ref = load("C:\Users\LY546\Documents\MATLAB\misc\ops_reference.mat"); 
%             fnames = fieldnames(ops_ref); 
%             
%             for ifield = 1:length(fnames)
%                 fname = fnames{ifield};
%                 fieldType = class(ops_ref.(fname)); 
%                 
%                 if ~isfield(OpsNew, fname)
%                     msg = sprintf('Field not found for variable: %s', fname); 
%                     f = errordlg(msg,'Missing Field Error');
%                 end
%                 
%                 if class(OpsNew.(fname)) ~= fieldType
%                     msg = sprintf('Invalid field type for variable: %s', fname); 
%                     f = errordlg(msg ,'Error');
%                 end
% 
%             end
        
        end
        
        
        function [] = update_ops(app) %Update Ops struct based on GUI fields
%             validate_ops(app); 
            
            for ivar = 1:height(app.OpsMeta)
                fprintf('Updating Ops: %s\n', app.OpsMeta.name{ivar}); 
                app.Ops.(app.OpsMeta.name{ivar}) = app.(app.OpsMeta.GUIname{ivar}).Value;
            end            
        end
        
        function [] = update_fields(app) %Update GUI fields from Ops struct
            
            for ivar = 1:height(app.OpsMeta)
                fprintf('Updating GUI field: %s\n', app.OpsMeta.GUIname{ivar});
                
                if ~isfield(app.Ops, app.OpsMeta.name{ivar})
                    warning(['GUI parameter ' app.OpsMeta.name{ivar} ' not found in Ops. Applying default value.'])
                else
                    app.(app.OpsMeta.GUIname{ivar}).Value = app.Ops.(app.OpsMeta.name{ivar});
                end
            end            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %Check to see if current ops exists for PC name, if not load template
            current_ops = string(app.OpsFolder / 'simple_extract_data_ops_current_' + getenv('computername') + '.mat');
            template_ops = string(app.OpsFolder / 'simple_extract_data_ops_template.mat');

            if exist(current_ops, 'file')
                app.OPS_CURRENT_PATH = current_ops;
                app.StatusLabel.Text = 'Status: Loaded last user ops successfully';
            else
                app.OPS_CURRENT_PATH = template_ops;
                app.StatusLabel.Text = 'Status: Loaded template ops successfully';
            end
            
            app.OPS_META_PATH = app.OpsFolder \ 'simple_extract_data_ops_mapping.xlsx';
             
            app.Ops = load(string(app.OPS_CURRENT_PATH));

            
            %         opts = detectImportOptions('patients.xls');
            importOps = detectImportOptions(string(app.OPS_META_PATH));
            app.OpsMeta = readtable(string(app.OPS_META_PATH)); 
            
            for ivar = 1:height(app.OpsMeta)
                app.(app.OpsMeta.GUIname{ivar}).Tooltip = app.OpsMeta.GUItooltip{ivar};
%                 app.(app.OpsMeta.GUIname{ivar}).Tooltip = "test";
            end  
            update_fields(app); % update GUI fields based on loaded Ops
            
        end

        % Button pushed function: SaveAsButton
        function SaveAsButtonPushed(app, event)
            update_ops(app); % update Ops based on GUI field values
            
            [f, fpath] = uiputfile('ops_my-custom-suffix.mat'); 
            drawnow; figure(app.ExtractDataOptionsGUIUIFigure) %Bring GUI to front
            
            if isequal(f,0) || isequal(fpath,0)
               app.StatusLabel.Text = "Status: 'Save as' cancelled.";
            else
                Ops = app.Ops; 
                save([fpath filesep f], '-struct', 'Ops'); 
                
                app.StatusLabel.Text = 'Status: Saved successfully';
            end
        end

        % Button pushed function: LoadDemoButton
        function LoadDemoButtonPushed(app, event)
            template_path = string(app.OpsFolder / 'simple_extract_data_ops_template.mat');
            
            app.Ops = load(template_path);
            update_fields(app); % update GUI fields based on loaded Ops
        
            app.StatusLabel.Text = 'Status: Loaded template successfully';
            
            %This used to be a "Make Default" button. Here is the code in
            %case we want to do that again:
%             update_ops(app); % update Ops based on GUI field values
%
%             Ops = app.Ops; 
%             save(string(app.OPS_CURRENT_PATH), '-struct', 'Ops'); 
%             
%             app.StatusLabel.Text = 'Status: Saved to ops_default.mat successfully';
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            
            [f, fpath] = uigetfile(string(app.OpsFolder / '*.mat'));
            drawnow; figure(app.ExtractDataOptionsGUIUIFigure) %Bring GUI to front
            
            if isequal(f,0) || isequal(fpath,0)
               app.StatusLabel.Text = "Status: 'Load' cancelled.";
            else
                app.Ops = load([fpath filesep f]);
                update_fields(app); % update GUI fields based on loaded Ops
            
                app.StatusLabel.Text = 'Status: Loaded successfully';
            end
        end

        % Callback function: BlocksPathEditField, 
        % BlocksPathEditField, IncludeMatchesCheckBox, 
        % InfoFileEditField, InfoFileEditField, MatchFileEditField, 
        % PlotExtractedDataCheckBox, PlotFiguresCheckBox, 
        % PreviewBlockResponsesCheckBox, SaveExtractedDataCheckBox, 
        % SaveFiguresCheckBox, SavePathEditField, 
        % SaveRespCellFigsOnlyCheckBox, UseReliabilityCheckBox, 
        % ValidateopsButton
        function ValidateopsButtonPushed(app, event)
%             validate_ops(app);
            update_ops(app); %**If you change any buttons that call update_ops, remember to check excel spreadsheet for consistency**
        end

        % Button pushed function: InfoFilePathSelectButton
        function InfoFilePathSelectButtonPushed(app, event)
            [f, fpath] = uigetfile('*.xlsx');
            %Update field only if user selected a file, otherwise keep previous value
            if ~isequal(f, 0)
                app.InfoFileEditField.Value = string(fpath) + f;
            end
            drawnow; figure(app.ExtractDataOptionsGUIUIFigure) %Bring GUI to front
        end

        % Button pushed function: BlocksPathSelectButton
        function BlocksPathSelectButtonPushed(app, event)
            [fpath] = uigetdir(); 
            %Update field only if user selected a file, otherwise keep previous value
            if ~isequal(fpath,0)
                app.BlocksPathEditField.Value = string(fpath);
            end
            drawnow; figure(app.ExtractDataOptionsGUIUIFigure) %Bring GUI to front
        end

        % Button pushed function: SavePathSelectButton
        function SavePathSelectButtonPushed(app, event)
            [fpath] = uigetdir();
            %Update field only if user selected a file, otherwise keep previous value
            if ~isequal(fpath,0)
                app.SavePathEditField.Value = string(fpath);
            end
            drawnow; figure(app.ExtractDataOptionsGUIUIFigure) %Bring GUI to front
        end

        % Close request function: ExtractDataOptionsGUIUIFigure
        function ExtractDataOptionsGUIUIFigureCloseRequest(app, event)
%             uiconfirm(fig,message,title); 
%             YN = uiconfirm(app.ExtractDataOptionsGUIUIFigure,'Do you want to exit? Updates will be lost.', 'Close request');
%             if strcmpi(YN,'OK')
%                 delete(app);
%             end
            delete(app);

        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            update_ops(app);
            
            %Save current Ops to be loaded at start next time
            Ops = app.Ops; 
            save(string(app.OpsFolder / 'simple_extract_data_ops_current_' + getenv('computername') + '.mat'), '-struct', 'Ops'); 
                
            % push app.Ops to a variable in the base workspace
            assignin('base','Ops', app.Ops); 
            delete(app);
        end

        % Drop down opening function: ShuffleTypeDropDown
        function ShuffleTypeDropDownOpening(app, event)

        end

        % Value changed function: ShuffleTypeDropDown
        function ShuffleTypeDropDownValueChanged(app, event)
            value = app.ShuffleTypeDropDown.Value;

        end

        % Button pushed function: MatchFilePathSelectButton
        function MatchFilePathSelectButtonPushed(app, event)
            [f, fpath] = uigetfile('*.xlsx'); 
            %Update field only if user selected a file, otherwise keep previous value
            if ~isequal(f,0)
                app.MatchFileEditField.Value = string(fpath) + f; 
            end
            drawnow; figure(app.ExtractDataOptionsGUIUIFigure) %Bring GUI to front
        end

        % Button pushed function: InfoFilePathClearButton
        function InfoFilePathClearButtonPushed(app, event)
            app.InfoFileEditField.Value = ''; 
        end

        % Button pushed function: MatchFilePathClearButton
        function MatchFilePathClearButtonPushed(app, event)
            app.MatchFileEditField.Value = ''; 
        end

        % Button pushed function: BlocksPathClearButton
        function BlocksPathClearButtonPushed(app, event)
            app.BlocksPathEditField.Value = '';
        end

        % Button pushed function: SavePathClearButton
        function SavePathClearButtonPushed(app, event)
            app.SavePathEditField.Value = ''; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExtractDataOptionsGUIUIFigure = uifigure('Visible', 'off');
            app.ExtractDataOptionsGUIUIFigure.Name = 'Extract Data Options GUI';
            app.ExtractDataOptionsGUIUIFigure.CloseRequestFcn = createCallbackFcn(app, @ExtractDataOptionsGUIUIFigureCloseRequest, true);
            app.IOPanel = uipanel(app.ExtractDataOptionsGUIUIFigure);
            app.IOPanel.Title = 'I/O';
            app.LoadButton = uibutton(app.IOPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveAsButton = uibutton(app.IOPanel, 'push');
            app.SaveAsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAsButtonPushed, true);
            app.SaveAsButton.Text = 'Save As';
            app.LoadDemoButton = uibutton(app.IOPanel, 'push');
            app.LoadDemoButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDemoButtonPushed, true);
            app.LoadDemoButton.Text = 'Load Demo';
            app.ValidateopsButton = uibutton(app.IOPanel, 'push');
            app.ValidateopsButton.ButtonPushedFcn = createCallbackFcn(app, @ValidateopsButtonPushed, true);
            app.ValidateopsButton.Text = 'Validate ops';
            app.PathsPanel = uipanel(app.ExtractDataOptionsGUIUIFigure);
            app.PathsPanel.Title = 'Paths';
            app.InfoFilePathSelectButton = uibutton(app.PathsPanel, 'push');
            app.InfoFilePathSelectButton.ButtonPushedFcn = createCallbackFcn(app, @InfoFilePathSelectButtonPushed, true);
            app.InfoFilePathSelectButton.Text = 'Select';
            app.InfoFileEditFieldLabel = uilabel(app.PathsPanel);
            app.InfoFileEditFieldLabel.Text = 'Info File';
            app.InfoFileEditField = uieditfield(app.PathsPanel, 'text');
            app.InfoFileEditField.ValueChangedFcn = createCallbackFcn(app, @ValidateopsButtonPushed, true);
            app.InfoFileEditField.ValueChangingFcn = createCallbackFcn(app, @ValidateopsButtonPushed, true);
            app.InfoFileEditField.Tooltip = {'test'; ''};
            app.BlocksPathSelectButton = uibutton(app.PathsPanel, 'push');
            app.BlocksPathSelectButton.ButtonPushedFcn = createCallbackFcn(app, @BlocksPathSelectButtonPushed, true);
            app.BlocksPathSelectButton.Text = 'Select';
            app.BlocksPathEditFieldLabel = uilabel(app.PathsPanel);
            app.BlocksPathEditFieldLabel.Text = 'Blocks Path';
            app.BlocksPathEditField = uieditfield(app.PathsPanel, 'text');
            app.BlocksPathEditField.ValueChangedFcn = createCallbackFcn(app, @ValidateopsButtonPushed, true);
            app.BlocksPathEditField.ValueChangingFcn = createCallbackFcn(app, @ValidateopsButtonPushed, true);
            app.SavePathSelectButton = uibutton(app.PathsPanel, 'push');
            app.SavePathSelectButton.ButtonPushedFcn = createCallbackFcn(app, @SavePathSelectButtonPushed, true);
            app.SavePathSelectButton.Text = 'Select';
            app.SavePathEditFieldLabel = uilabel(app.PathsPanel);
            app.SavePathEditFieldLabel.Text = 'Save Path';
            app.SavePathEditField = uieditfield(app.PathsPanel, 'text');
            app.SavePathEditField.ValueChangedFcn = createCallbackFcn(app, @ValidateopsButtonPushed, true);
            app.MatchFileEditFieldLabel = uilabel(app.PathsPanel);
            app.MatchFileEditFieldLabel.Text = 'Match File';
            app.MatchFileEditField = uieditfield(app.PathsPanel, 'text');
            app.MatchFileEditField.ValueChangedFcn = createCallbackFcn(app, @ValidateopsButtonPushed, true);
            app.MatchFilePathSelectButton = uibutton(app.PathsPanel, 'push');
            app.MatchFilePathSelectButton.ButtonPushedFcn = createCallbackFcn(app, @MatchFilePathSelectButtonPushed, true);
            app.MatchFilePathSelectButton.Text = 'Select';
            app.InfoFilePathClearButton = uibutton(app.PathsPanel, 'push');
            app.InfoFilePathClearButton.ButtonPushedFcn = createCallbackFcn(app, @InfoFilePathClearButtonPushed, true);
            app.InfoFilePathClearButton.Text = 'Clear';
            app.BlocksPathClearButton = uibutton(app.PathsPanel, 'push');
            app.BlocksPathClearButton.ButtonPushedFcn = createCallbackFcn(app, @BlocksPathClearButtonPushed, true);
            app.BlocksPathClearButton.Text = 'Clear';
            app.SavePathClearButton = uibutton(app.PathsPanel, 'push');
            app.SavePathClearButton.ButtonPushedFcn = createCallbackFcn(app, @SavePathClearButtonPushed, true);
            app.SavePathClearButton.Text = 'Clear';
            app.MatchFilePathClearButton = uibutton(app.PathsPanel, 'push');
            app.MatchFilePathClearButton.ButtonPushedFcn = createCallbackFcn(app, @MatchFilePathClearButtonPushed, true);
            app.MatchFilePathClearButton.Text = 'Clear';
            app.GlobalPanel = uipanel(app.ExtractDataOptionsGUIUIFigure);
            app.GlobalPanel.Title = 'Global';
            app.StimProtocolLabel = uilabel(app.GlobalPanel);
            app.StimProtocolLabel.Text = 'Stim Protocol';
            app.StimProtocolDropDown = uidropdown(app.GlobalPanel);
            app.StimProtocolDropDown.Items = {'FreqDisc', 'RF', 'FM', 'SAM', 'SAMfreq', 'ABI', 'ABI SAM', 'H2O', 'Noiseburst ITI', 'Air', 'AirSham', 'Spontaneous', 'Maryse Behavior', 'AMBehavior', 'Markpoints', 'Ripple', 'Ephys', 'Vocalizations', 'Loco', 'ABCXYZ Pattern', 'ABCXYZ Random'};
            app.StimProtocolDropDown.Value = 'FreqDisc';
            app.SmoothingWindowEditFieldLabel = uilabel(app.GlobalPanel);
            app.SmoothingWindowEditFieldLabel.Text = 'Smoothing Window';
            app.SmoothingWindowEditField = uieditfield(app.GlobalPanel, 'numeric');
            app.LocoFilterListBoxLabel = uilabel(app.GlobalPanel);
            app.LocoFilterListBoxLabel.Text = 'Loco Filter';
            app.LocoFilterListBox = uilistbox(app.GlobalPanel);
            app.LocoFilterListBox.Items = {'All', 'Running', 'NotRunning'};
            app.LocoFilterListBox.Multiselect = 'on';
            app.LocoFilterListBox.Value = {'All'};
            app.SaveFullTraceCheckBox = uicheckbox(app.GlobalPanel);
            app.SaveFullTraceCheckBox.Text = 'Save Full Trace';
            app.SaveFullTraceCheckBox.Value = true;
            app.CheckSingleTrialRespCheckBox = uicheckbox(app.GlobalPanel);
            app.CheckSingleTrialRespCheckBox.Text = 'Check Single Trial Resp';
            app.CheckSingleTrialRespCheckBox.Value = true;
            app.RemoveUnbalancedStimCheckBox = uicheckbox(app.GlobalPanel);
            app.RemoveUnbalancedStimCheckBox.Text = 'Remove Unbalanced Stim';
            app.SavingandPlottingPanel = uipanel(app.ExtractDataOptionsGUIUIFigure);
            app.SavingandPlottingPanel.Title = 'Saving and Plotting';
            app.PlotExtractedDataCheckBox = uicheckbox(app.SavingandPlottingPanel);
            app.PlotExtractedDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @ValidateopsButtonPushed, true);
            app.PlotExtractedDataCheckBox.Text = 'Plot Extracted Data';
            app.PlotExtractedDataCheckBox.Value = true;
            app.SaveExtractedDataCheckBox = uicheckbox(app.SavingandPlottingPanel);
            app.SaveExtractedDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @ValidateopsButtonPushed, true);
            app.SaveExtractedDataCheckBox.Text = 'Save Extracted Data';
            app.SaveExtractedDataCheckBox.Value = true;
            app.PlotFiguresCheckBox = uicheckbox(app.SavingandPlottingPanel);
            app.PlotFiguresCheckBox.ValueChangedFcn = createCallbackFcn(app, @ValidateopsButtonPushed, true);
            app.PlotFiguresCheckBox.Text = 'Plot Figures';
            app.SaveFiguresCheckBox = uicheckbox(app.SavingandPlottingPanel);
            app.SaveFiguresCheckBox.ValueChangedFcn = createCallbackFcn(app, @ValidateopsButtonPushed, true);
            app.SaveFiguresCheckBox.Text = 'Save Figures';
            app.IncludeMatchesCheckBox = uicheckbox(app.SavingandPlottingPanel);
            app.IncludeMatchesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ValidateopsButtonPushed, true);
            app.IncludeMatchesCheckBox.Text = 'Include Matches';
            app.SaveRespCellFigsOnlyCheckBox = uicheckbox(app.SavingandPlottingPanel);
            app.SaveRespCellFigsOnlyCheckBox.ValueChangedFcn = createCallbackFcn(app, @ValidateopsButtonPushed, true);
            app.SaveRespCellFigsOnlyCheckBox.Text = 'Save Resp Cell Figs Only';
            app.SaveRespCellFigsOnlyCheckBox.Value = true;
            app.PreviewBlockResponsesCheckBox = uicheckbox(app.SavingandPlottingPanel);
            app.PreviewBlockResponsesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ValidateopsButtonPushed, true);
            app.PreviewBlockResponsesCheckBox.Text = 'Preview Block Responses';
            app.StatusLabel = uilabel(app.ExtractDataOptionsGUIUIFigure);
            app.StatusLabel.Text = 'Status: ';
            app.RunButton = uibutton(app.ExtractDataOptionsGUIUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run...';
            app.ReliabilityPanel = uipanel(app.ExtractDataOptionsGUIUIFigure);
            app.ReliabilityPanel.Title = 'Reliability';
            app.UseReliabilityCheckBox = uicheckbox(app.ReliabilityPanel);
            app.UseReliabilityCheckBox.ValueChangedFcn = createCallbackFcn(app, @ValidateopsButtonPushed, true);
            app.UseReliabilityCheckBox.Text = 'Use Reliability';
            app.UseReliabilityCheckBox.Value = true;
            app.UseNeighborsCheckBox = uicheckbox(app.ReliabilityPanel);
            app.UseNeighborsCheckBox.Text = 'Use Neighbors';
            app.UseNeighborsCheckBox.Value = true;
            app.ReliabilityShufflesEditFieldLabel = uilabel(app.ReliabilityPanel);
            app.ReliabilityShufflesEditFieldLabel.Text = 'Reliability Shuffles';
            app.ReliabilityShufflesEditField = uieditfield(app.ReliabilityPanel, 'numeric');
            app.ReliabilityShufflesEditField.Limits = [0 Inf];
            app.ReliabilityShufflesEditField.Value = 1000;
            app.ShuffleTypeDropDownLabel = uilabel(app.ReliabilityPanel);
            app.ShuffleTypeDropDownLabel.Text = 'Shuffle Type';
            app.ShuffleTypeDropDown = uidropdown(app.ReliabilityPanel);
            app.ShuffleTypeDropDown.Items = {'Blanks', 'Shifted'};
            app.ShuffleTypeDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ShuffleTypeDropDownOpening, true);
            app.ShuffleTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ShuffleTypeDropDownValueChanged, true);
            app.ShuffleTypeDropDown.Value = 'Blanks';
            app.ReliabilitypvalueLabel = uilabel(app.ReliabilityPanel);
            app.ReliabilitypvalueLabel.Text = 'Reliability p-value';
            app.ReliabilitypvalueEditField = uieditfield(app.ReliabilityPanel, 'numeric');
            app.ReliabilitypvalueEditField.Limits = [0 1];
            app.ReliabilitypvalueEditField.Value = 0.05;
            app.NeighborpvalueEditFieldLabel = uilabel(app.ReliabilityPanel);
            app.NeighborpvalueEditFieldLabel.Text = 'Neighbor p-value';
            app.NeighborpvalueEditField = uieditfield(app.ReliabilityPanel, 'numeric');
            app.NeighborpvalueEditField.Limits = [0 1];
            app.NeighborpvalueEditField.Value = 0.05;
            app.ReliabilityTypeDropDownLabel = uilabel(app.ReliabilityPanel);
            app.ReliabilityTypeDropDownLabel.Text = 'Reliability Type';
            app.ReliabilityTypeDropDown = uidropdown(app.ReliabilityPanel);
            app.ReliabilityTypeDropDown.Items = {'Pearsons', 'XCorr'};
            app.ReliabilityTypeDropDown.Value = 'Pearsons';
            app.AdjustpvalueCheckBox = uicheckbox(app.ReliabilityPanel);
            app.AdjustpvalueCheckBox.Text = 'Adjust p-value';
            app.AdjustpvalueCheckBox.Value = true;
            app.AdjustneighborpvalueCheckBox = uicheckbox(app.ReliabilityPanel);
            app.AdjustneighborpvalueCheckBox.Text = 'Adjust neighbor p-value';
            app.AdjustneighborpvalueCheckBox.Value = true;
            app.PeakDetectionPanel = uipanel(app.ExtractDataOptionsGUIUIFigure);
            app.PeakDetectionPanel.Title = 'Peak Detection';
            app.ZLevelEditFieldLabel = uilabel(app.PeakDetectionPanel);
            app.ZLevelEditFieldLabel.Text = 'Z Level';
            app.ZLevelEditField = uieditfield(app.PeakDetectionPanel, 'numeric');
            app.ZLevelEditField.Limits = [0 Inf];
            app.ZLevelEditField.Value = 1;
            app.AUCLevelEditFieldLabel = uilabel(app.PeakDetectionPanel);
            app.AUCLevelEditFieldLabel.Text = 'AUC Level';
            app.AUCLevelEditField = uieditfield(app.PeakDetectionPanel, 'numeric');
            app.AUCLevelEditField.Limits = [0 Inf];
            app.AUCLevelEditField.Value = 5;
            app.PeakDurationEditFieldLabel = uilabel(app.PeakDetectionPanel);
            app.PeakDurationEditFieldLabel.Text = 'Peak Duration';
            app.PeakDurationEditField = uieditfield(app.PeakDetectionPanel, 'numeric');
            app.PeakDurationEditField.Limits = [0 Inf];
            app.PeakDurationEditField.Value = 0.2;
            app.ConcatenateBlocksPanel = uipanel(app.ExtractDataOptionsGUIUIFigure);
            app.ConcatenateBlocksPanel.Title = 'Concatenate Blocks';
            app.ConcatenateBlocksCheckBox = uicheckbox(app.ConcatenateBlocksPanel);
            app.ConcatenateBlocksCheckBox.Text = 'Concatenate Blocks';
            app.RemoveOldBlocksCheckBox = uicheckbox(app.ConcatenateBlocksPanel);
            app.RemoveOldBlocksCheckBox.Text = 'Remove Old Blocks';
            app.ConcatenatebyDropDownLabel = uilabel(app.ConcatenateBlocksPanel);
            app.ConcatenatebyDropDownLabel.Text = 'Concatenate by';
            app.ConcatenatebyDropDown = uidropdown(app.ConcatenateBlocksPanel);
            app.ConcatenatebyDropDown.Items = {'Day', 'Session', 'FOV'};
            app.ConcatenatebyDropDown.Value = 'Day';
            app.MarkPointsPanel = uipanel(app.ExtractDataOptionsGUIUIFigure);
            app.MarkPointsPanel.Title = 'Mark Points';
            app.AnalysisDropDownLabel = uilabel(app.MarkPointsPanel);
            app.AnalysisDropDownLabel.Text = 'Analysis';
            app.AnalysisDropDown = uidropdown(app.MarkPointsPanel);
            app.AnalysisDropDown.Items = {'Regular', 'Uncaging Shutter Control', 'XY Spatial', 'Z Spatial', 'Remove trials without activation'};
            app.AnalysisDropDown.Value = 'Regular';
            % Show the figure after all components are created
            app.ExtractDataOptionsGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simple_extract_data_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ExtractDataOptionsGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExtractDataOptionsGUIUIFigure)
        end
    end
end